classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdquisicionOSC01UIFigure  matlab.ui.Figure
        lbl_NombreArchivo         matlab.ui.control.Label
        btn_clipboardCH2          matlab.ui.control.Button
        btn_clipboardCH1          matlab.ui.control.Button
        btn_exportCH2             matlab.ui.control.Button
        btn_exportCH1             matlab.ui.control.Button
        btn_ExportCSV             matlab.ui.control.Button
        btn_ExportMAT             matlab.ui.control.Button
        btn_abrirArchivoDAT       matlab.ui.control.Button
        btn_imgHelp               matlab.ui.control.Image
        tab_AjusteGraficas        matlab.ui.container.TabGroup
        tab_AjusteTiempo          matlab.ui.container.Tab
        btn_tRestore              matlab.ui.control.Button
        ef_valPretrigger          matlab.ui.control.NumericEditField
        lbl_valPretrigger         matlab.ui.control.Label
        ef_endTime                matlab.ui.control.NumericEditField
        lbl_endTime               matlab.ui.control.Label
        ef_startTime              matlab.ui.control.NumericEditField
        lbl_startTime             matlab.ui.control.Label
        ef_offsetTime             matlab.ui.control.NumericEditField
        OffsetLabel               matlab.ui.control.Label
        dd_factorTime             matlab.ui.control.DropDown
        lbl_factorTime            matlab.ui.control.Label
        tab_AjusteCH1             matlab.ui.container.Tab
        btn_ch1Restore            matlab.ui.control.Button
        ef_ch1YMax                matlab.ui.control.NumericEditField
        lbl_ch1YMax               matlab.ui.control.Label
        ef_ch1YMin                matlab.ui.control.NumericEditField
        lbl_ch1YMin               matlab.ui.control.Label
        ef_ch1Factor              matlab.ui.control.NumericEditField
        lbl_ch1Factor             matlab.ui.control.Label
        ef_ch1Etiqueta            matlab.ui.control.EditField
        lbl_ch1Etiqueta           matlab.ui.control.Label
        ef_ch1Titulo              matlab.ui.control.EditField
        lbl_ch1Titulo             matlab.ui.control.Label
        tab_AjusteCH2             matlab.ui.container.Tab
        btn_ch2Restore            matlab.ui.control.Button
        ef_ch2YMax                matlab.ui.control.NumericEditField
        lbl_ch2Ymax               matlab.ui.control.Label
        ef_ch2YMin                matlab.ui.control.NumericEditField
        lbl_ch2YMin               matlab.ui.control.Label
        ef_ch2Factor              matlab.ui.control.NumericEditField
        lbl_ch2Factor             matlab.ui.control.Label
        ef_ch2Etiqueta            matlab.ui.control.EditField
        lbl_ch2Etiqueta           matlab.ui.control.Label
        ef_ch2Titulo              matlab.ui.control.EditField
        lbl_ch2Titulo             matlab.ui.control.Label
        tab_TipoConexion          matlab.ui.container.TabGroup
        ConexionSerialTab         matlab.ui.container.Tab
        lbl_ch1Enable             matlab.ui.control.Label
        lbl_ch2Enable             matlab.ui.control.Label
        lamp_ch2Enable            matlab.ui.control.Lamp
        sw_ch2Enable              matlab.ui.control.Switch
        btn_serialRead            matlab.ui.control.Button
        lamp_ch1Enable            matlab.ui.control.Lamp
        sw_ch1Enable              matlab.ui.control.Switch
        btn_serialConnect         matlab.ui.control.Button
        ef_serialBPS              matlab.ui.control.NumericEditField
        lbl_serialBPS             matlab.ui.control.Label
        btn_UpdateSerialPort      matlab.ui.control.Button
        dd_serialPort             matlab.ui.control.DropDown
        lbl_serialPort            matlab.ui.control.Label
        img_Logo                  matlab.ui.control.Image
        graph_CH2                 matlab.ui.control.UIAxes
        graph_CH1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        raw_data;       %Valores leidos del Osciloscopio (dt, ch1, ch2, ch1_mult, ch2_mult, ch1_zero, ch2_zero, ch1_off, ch2_off)
        computed_data;  
        factor_ch1;
        factor_ch2;
        factor_t;
        offset_t;
        serial;
        buttons_lock;
    end


    methods (Access = private)
        
        function result = LeerValores(app)
            
            result = 1;
            d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Limpiando Buffers...', 'Title', 'Leyendo Valores');

            try
                % Limpio los buffers
                flush(app.serial.connection,"input");
                flush(app.serial.connection, "output");
                clear app.raw_data.ch1 app.raw_data.ch2;
                d.Value = 0.1;
                d.Message = "Leyendo configuraciones...";
                

                % Leo el incremento de tiempo configurado por los s/div
                app.raw_data.dt = str2double(writeread(app.serial.connection,"WFMPRE:CH1:XINCR?")); pause(0.05);
                app.raw_data.trig_pos = str2double(writeread(app.serial.connection, "HORIZONTAL:TRIGGER:POSITION?")); pause(0.05);


                % Segun si los canales estan habilitados, corresponde manipular al que no lo este.
                if(strcmp(app.sw_ch1Enable.Value, 'On') && strcmp(app.sw_ch2Enable.Value, 'Off'))     % CH1[ON]   CH2[OFF]
                    % -------------------------------------------------------------------------------------------------> LEO CH1
                    % Primero se solicita la curva (Obligatorio) -> CH1
                    d.Message = "Descargando muestras del Canal 1...";
                    writeline(app.serial.connection,"DATA:SOURCE CH1"); pause(0.5);
                    writeline(app.serial.connection,"CURVE?"); pause(0.5);
                    ch_t = readline(app.serial.connection);
                    app.raw_data.ch1 = str2double(split(ch_t, ","));
                    d.Value = 0.2;
            
                    % Leo los multiplicadores, los zero y los offsets de los canales
                    d.Message = "Leyendo parametros del Canal 1...";
                    app.raw_data.ch1_mult = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YMULT?")); pause(0.05);
                    d.Value = 0.4;
                    app.raw_data.ch1_zero = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YZERO?")); pause(0.05);
                    d.Value = 0.5;
                    app.raw_data.ch1_off = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YOFF?")); pause(0.05);
                    % ----------------------------------------------------------------------------------------------------------
                    app.raw_data.ch2 = zeros(length(app.raw_data.ch1), 1);
                    app.raw_data.ch2_mult = 0;
                    app.raw_data.ch2_zero = 0;
                    app.raw_data.ch2_off = 0;
                    app.raw_data.trig_pos = (app.raw_data.trig_pos/100) * app.raw_data.dt * length(app.raw_data.ch1);
                elseif(strcmp(app.sw_ch1Enable.Value, 'Off') && strcmp(app.sw_ch2Enable.Value, 'On')) % CH1[OFF]  CH2[ON]
                    % -------------------------------------------------------------------------------------------------> LEO CH2
                    % Primero se solicita la curva (Obligatorio) -> CH2
                    d.Message = "Descargando muestras del Canal 2...";
                    writeline(app.serial.connection,"DATA:SOURCE CH2"); pause(0.5);
                    writeline(app.serial.connection,"CURVE?"); pause(0.5);
                    ch_t = readline(app.serial.connection);
                    app.raw_data.ch2 = str2double(split(ch_t, ","));
                    d.Value = 0.8;
                    
                    % Leo los multiplicadores, los zero y los offsets de los canales
                    d.Message = "Leyendo parametros del Canal 2...";
                    app.raw_data.ch2_mult = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YMULT?")); pause(0.05);
                    d.Value = 0.85;
                    app.raw_data.ch2_zero = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YZERO?")); pause(0.05);
                    d.Value = 0.9;
                    app.raw_data.ch2_off = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YOFF?")); pause(0.05);
                    % ----------------------------------------------------------------------------------------------------------
                    app.raw_data.ch1 = zeros(length(app.raw_data.ch2), 1);
                    app.raw_data.ch1_mult = 0;
                    app.raw_data.ch1_zero = 0;
                    app.raw_data.ch1_off = 0;
                    app.raw_data.trig_pos = (app.raw_data.trig_pos/100) * app.raw_data.dt * length(app.raw_data.ch2);
                elseif(strcmp(app.sw_ch1Enable.Value, 'On') && strcmp(app.sw_ch2Enable.Value, 'On'))  % CH1[ON]   CH2[ON]
                    % -------------------------------------------------------------------------------------------------> LEO CH1
                    % Primero se solicita la curva (Obligatorio) -> CH1
                    d.Message = "Descargando muestras del Canal 1...";
                    writeline(app.serial.connection,"DATA:SOURCE CH1"); pause(0.5);
                    writeline(app.serial.connection,"CURVE?"); pause(0.5);
                    ch_t = readline(app.serial.connection);
                    app.raw_data.ch1 = str2double(split(ch_t, ","));
                    d.Value = 0.2;
            
                    % Leo los multiplicadores, los zero y los offsets de los canales
                    d.Message = "Leyendo parametros del Canal 1...";
                    app.raw_data.ch1_mult = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YMULT?")); pause(0.05);
                    d.Value = 0.4;
                    app.raw_data.ch1_zero = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YZERO?")); pause(0.05);
                    d.Value = 0.5;
                    app.raw_data.ch1_off = str2double(writeread(app.serial.connection, "WFMPRE:CH1:YOFF?")); pause(0.05);
                    % ----------------------------------------------------------------------------------------------------------

                    % -------------------------------------------------------------------------------------------------> LEO CH2
                    % Primero se solicita la curva (Obligatorio) -> CH2
                    d.Message = "Descargando muestras del Canal 2...";
                    writeline(app.serial.connection,"DATA:SOURCE CH2"); pause(0.5);
                    writeline(app.serial.connection,"CURVE?"); pause(0.5);
                    ch_t = readline(app.serial.connection);
                    app.raw_data.ch2 = str2double(split(ch_t, ","));
                    d.Value = 0.8;
                    
                    % Leo los multiplicadores, los zero y los offsets de los canales
                    d.Message = "Leyendo parametros del Canal 2...";
                    app.raw_data.ch2_mult = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YMULT?")); pause(0.05);
                    d.Value = 0.85;
                    app.raw_data.ch2_zero = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YZERO?")); pause(0.05);
                    d.Value = 0.9;
                    app.raw_data.ch2_off = str2double(writeread(app.serial.connection, "WFMPRE:CH2:YOFF?")); pause(0.05);
                    % ----------------------------------------------------------------------------------------------------------
                    app.raw_data.trig_pos = (app.raw_data.trig_pos/100) * app.raw_data.dt * length(app.raw_data.ch1);
                else                                                                                  % CH1[OFF]  CH2[OFF] -> Error
                    errorStruct.message = 'Se debe seleccionar al menos un canal!';
                    errorStruct.identifier = 'LeerValores:NoChannel';
                    error(errorStruct);
                end

                d.Value = 1;

% -----------------------------------------------------------------------------------------------------> Para Revisar      
%                 % Leo los canales en formato BinBlock (To-Do)
%                 ch1_scale = str2double(writeread(app.serial.connection, "CH1:SCALE?")); pause(0.05);
%                 v1 = (max(app.raw_data.ch1) - app.raw_data.ch1_off) * app.raw_data.ch1_mult + app.raw_data.ch1_zero;
%                 v2 = (max(app.raw_data.ch1) - app.raw_data.ch1_off) * (10*ch1_scale/(2^16)) + app.raw_data.ch1_zero;
%                 uialert(app.AdquisicionOSC01UIFigure, ["V1:" + string(v1); "V2:" + string(v2)], "Datos");
%                
%                 % Leo los canales
%                 writeline(app.serial.connection, "DATA:SOURCE CH1"); pause(0.05);
%                 writeline(app.serial.connection, "CURVE?"); pause(0.05);
%                 app.raw_data.ch1 = readbinblock(app.serial.connection,"int16");
%                 d.Value = 0.8;
% 
%                 writeline(app.serial.connection, "DATA:SOURCE CH2"); pause(0.05);
%                 writeline(app.serial.connection, "CURVE?"); pause(0.05);
%                 app.raw_data.ch2 = readbinblock(app.serial.connection, "int16");
%                 d.Value = 0.99;
% -------------------------------------------------------------------------------------------------------------------
                
                % Si no se recibio ningun dato en alguno de los canales muestro una atleta.
                if isempty(app.raw_data.ch1) || isempty(app.raw_data.ch2)
                    errorStruct.message = 'No se logro leer los valores de uno o ambos canales';
                    errorStruct.identifier = 'LeerValores:ChanelError';
                    error(errorStruct);
                end


            catch ME
                 result = 0;
                 switch(ME.identifier)
                     case 'transportclients:string:timeoutToken'
                         uialert(app.AdquisicionOSC01UIFigure, 'No se recibio respuesta del Osciloscopio', 'Error', Icon='error');
                     case 'transportlib:transport:invalidConnectionState'
                         uialert(app.AdquisicionOSC01UIFigure, 'Se ha perdido la conexion con el Osciloscopio', 'Error', Icon='error');
                     case 'LeerValores:NoChannel'
                         uialert(app.AdquisicionOSC01UIFigure, 'Se debe seleccionar al menos un canal', 'Error', Icon='error');
                     case 'LeerValores:ChanelError'
                         uialert(app.AdquisicionOSC01UIFigure, 'No se recivieron los valores RAW del Osciloscopio', 'Error', Icon='error');
                     otherwise
                         uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                 end
            end

            close(d);
                
            % Factores por defecto
            app.dd_factorTime.Value = "1";  %Selector: Segundos
            %app.factor_ch1 = 1;
            %app.factor_ch2 = 1;
            app.factor_t = 1;
            app.offset_t = 0;

        end


        function plotFigures(app)
            if(app.buttons_lock)
                enableButtons(app);
                app.buttons_lock = 0;
            end

            bin2volt = @(raw, yoff, ymult, yzero)( (raw - yoff) .* ymult + yzero);

            
            % --------------------------------------------------------------------------------------------------------------------------> Canal 1
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch1Enable.Value, "On"))
                app.computed_data.ch1 = bin2volt(app.raw_data.ch1, app.raw_data.ch1_off, app.raw_data.ch1_mult, app.raw_data.ch1_zero); % Volts
            else
                app.computed_data.ch1 = app.raw_data.ch1; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch1) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_CH1, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch1 .* app.factor_ch1);
            grid(app.graph_CH1, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------


            % --------------------------------------------------------------------------------------------------------------------------> Canal 2
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch2Enable.Value, "On"))
                app.computed_data.ch2 = bin2volt(app.raw_data.ch2, app.raw_data.ch2_off, app.raw_data.ch2_mult, app.raw_data.ch2_zero); % Volts
            else
                app.computed_data.ch2 = app.raw_data.ch2; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch2) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_CH2, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch2 .* app.factor_ch2);  % Plot
            grid(app.graph_CH2, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------
                
            
            % Segun la unidad de tiempo seleccionada, cambio la leyenda
            switch(app.factor_t)
                case 1e-12
                    unit_t = 'ps';
                case 1e-9
                    unit_t = 'ns';
                case 0.000001
                    unit_t = 'µs';
                case 0.001
                    unit_t = 'ms';
                otherwise
                    unit_t = 's';
            end

            xlabel(app.graph_CH1, sprintf("Tiempo [%s]", unit_t));
            xlabel(app.graph_CH2, sprintf("Tiempo [%s]", unit_t));

            app.ef_valPretrigger.Value = app.raw_data.trig_pos ./ app.factor_t;
            app.lbl_valPretrigger.Text = sprintf("Pretriger [%s]", unit_t);


        end


        function updateValuesInBoxs(app)
            app.ef_startTime.Value = min(app.computed_data.t);  %Tiempo de inicio
            app.ef_endTime.Value = max(app.computed_data.t);    %Tiempo final
            app.ef_ch1YMin.Value = app.graph_CH1.YLim(1);       %CH1 Ymin
            app.ef_ch1YMax.Value = app.graph_CH1.YLim(2);       %CH1 Ymax
            app.ef_ch2YMin.Value = app.graph_CH2.YLim(1);       %CH2 YMin
            app.ef_ch2YMax.Value = app.graph_CH2.YLim(2);       %CH2 YmaX
            app.ef_ch1Factor.Value = app.factor_ch1;            %Factor CH1
            app.ef_ch2Factor.Value = app.factor_ch2;            %Factor CH2
        end


        function disableButtons(app)
            app.dd_factorTime.Enable = "off";

            app.ef_offsetTime.Enable = "off";
            app.ef_startTime.Enable = "off";
            app.ef_endTime.Enable = "off";
            app.btn_tRestore.Enable = "off";
            
            app.ef_ch1Factor.Enable = "off";
            app.ef_ch1YMin.Enable = "off";
            app.ef_ch1YMax.Enable = "off";
            app.btn_ch1Restore.Enable = "off";
            
            app.ef_ch2Factor.Enable = "off";
            app.ef_ch2YMin.Enable = "off";
            app.ef_ch2YMax.Enable = "off";
            app.btn_ch2Restore.Enable = "off";

            app.btn_ExportCSV.Enable = "off";
            app.btn_ExportMAT.Enable = "off";
        end


        function enableButtons(app)
            app.dd_factorTime.Enable = "on";

            app.ef_offsetTime.Enable = "on";
            app.ef_startTime.Enable = "on";
            app.ef_endTime.Enable = "on";
            app.btn_tRestore.Enable = "on";
            
            app.ef_ch1Factor.Enable = "on";
            app.ef_ch1YMin.Enable = "on";
            app.ef_ch1YMax.Enable = "on";
            app.btn_ch1Restore.Enable = "on";
            
            app.ef_ch2Factor.Enable = "on";
            app.ef_ch2YMin.Enable = "on";
            app.ef_ch2YMax.Enable = "on";
            app.btn_ch2Restore.Enable = "on";

            app.btn_ExportCSV.Enable = "on";
            app.btn_ExportMAT.Enable = "on";
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onLoad(app)
            app.buttons_lock = 1;
            disableButtons(app);
            app.dd_serialPort.Items = serialportlist;

            % Valores por defecto
            app.dd_factorTime.Value = "1";  %Selector: Segundos
            app.factor_ch1 = 1;
            app.factor_ch2 = 1;
            app.factor_t = 1;
            app.offset_t = 0;
        end

        % Button pushed function: btn_serialConnect
        function Serial_Connect(app, event)
            if(strcmp(app.btn_serialConnect.Text, "Conectar"))
                
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, Message="Abriendo el puerto COM...", Title="Conectando...", ShowPercentage="on");
                
                try
                    %------------------------------------------------------------------------------> CONEXION SERIAL + CONFIG
                    %Realizo la conexion
                    app.serial.connection = serialport(app.dd_serialPort.Value, app.ef_serialBPS.Value, "Timeout", 10);
                    
                    d.Value = .1;
                    d.Message = "Limpiando Buffers...";
                    pause(1);
                    flush(app.serial.connection, "input");
                    flush(app.serial.connection, "output");
    
                    % Encoding
                    d.Value = .4;
                    d.Message = "Configurando codificacion...";
                    writeline(app.serial.connection,"DATA:ENCDG ASCII"); pause(0.05);
                    writeline(app.serial.connection,"HEADER OFF");  pause(0.05);
                    writeline(app.serial.connection,"DATA:WIDTH 2"); pause(0.05);
                    
                    % Verifico el Modelo
                    d.Value = .8;
                    d.Message = "Verificando modelo del Osciloscopio...";
                    resp = writeread(app.serial.connection, "ID?");
                    
                    d.Value = 1;
                    pause(1);
                    if ~strcmp(strip(resp, 'right'), "ID TEK/TDS 360,CF:91.1CT,FV:v1.09")
                        e.message = 'Erro, el ID no corresponde al Osciloscopio TDS360';
                        e.identifier = 'Serial:IDError';
                        error(e);                        
                    end

                    uialert(app.AdquisicionOSC01UIFigure, "Conexión Establecida", "Conexion", Icon='success');
                    app.btn_serialConnect.Text = "Desconectar";
                    app.btn_serialRead.Enable = 1;
                    
                catch ME
                    app.serial.connection = [];     %Close(serial)
                    app.btn_serialRead.Enable = 0;
                    switch(ME.identifier)
                        case 'transportclients:string:timeoutToken'
                            uialert(app.AdquisicionOSC01UIFigure, 'No se recibio respuesta del Osciloscopio', 'Error', Icon='error');
                        case 'serialport:serialport:ConnectionFailed'
                            uialert(app.AdquisicionOSC01UIFigure, 'El puerto se encuentra en uso por otra aplicacion', 'Error', Icon='error');
                        case 'Serial:IDError'
                            uialert(app.AdquisicionOSC01UIFigure, "El Osciloscopio no es el Tektronix TDS360", "Conexion", Icon='error');
                        otherwise
                            uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                    end
                end

                close(d);
            else
                app.btn_serialConnect.Text = "Conectar";
                app.serial.connection = []; %Close(Serial)
                app.btn_serialRead.Enable = 0;
            end
        end

        % Button pushed function: btn_serialRead
        function Serial_Read(app, event)
            if LeerValores(app)
                app.lbl_NombreArchivo.Visible = 'off';
                plotFigures(app);

                axis(app.graph_CH1,"auto");
                axis(app.graph_CH2,"auto");
    
                updateValuesInBoxs(app);
            end
        end

        % Button pushed function: btn_ExportMAT
        function Export_MAT(app, event)
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';
            
            clear dt offset_t ch1 ch2 trig_pos;
            try
                dt = app.raw_data.dt;
                offset_t = app.offset_t * app.factor_t; %#ok<*NASGU,*ADPROPLC> 
                trig_pos = app.raw_data.trig_pos;
              
                ch1.raw = app.raw_data.ch1;
                ch1.ymult = app.raw_data.ch1_mult;
                ch1.yoff = app.raw_data.ch1_off;
                ch1.yzero = app.raw_data.ch1_zero;
                ch1.factor = app.factor_ch1;
                ch1.titulo = app.ef_ch1Titulo.Value;
                ch1.leyenda = app.ef_ch1Etiqueta.Value; %#ok<*STRNU> 
                
                ch2.raw = app.raw_data.ch2;
                ch2.ymult = app.raw_data.ch2_mult;
                ch2.yoff = app.raw_data.ch2_off;
                ch2.yzero = app.raw_data.ch2_zero;
                ch2.factor = app.factor_ch2;
                ch2.titulo = app.ef_ch2Titulo.Value;
                ch2.leyenda = app.ef_ch2Etiqueta.Value;
                
    
                uisave({'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'}, string(t))
            catch ME
                switch(ME.identifier)
                    case 'MATLAB:structRefFromNonStruct'
                        uialert(app.AdquisicionOSC01UIFigure, 'Se debe primero cargar alguna matriz de datos para poder exportar', 'Error', Icon='error');
                    otherwise
                        uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, "Error", Icon='error');
                end
            end
            clear dt ch1 ch2;
        end

        % Button pushed function: btn_ExportCSV
        function Export_CSV(app, event)
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';
            
            [filename,filepath] = uiputfile('*.csv', 'Elegir ubicacion', string(t) + ".csv");
            if ischar(filename)
                mtx = [app.computed_data.t, app.computed_data.ch1, app.computed_data.ch2];
                T = array2table(mtx, 'VariableNames', {'Tiempo', 'CH1', 'CH2'});
                writetable(T, [filepath filename], Delimiter='tab');
            end
        end

        % Button pushed function: btn_abrirArchivoDAT
        function Import_MAT(app, event)
            %Solicito que se abra el explorador para seleccionar el archivo
            %Especificamente con la extension .mat
            [file, path] = uigetfile('*.mat');
            
            if ~isequal(file,0)
                %Consulto si en el archivo, estan todas las variables que necesito.
                %Estas son: raw_ch1, raw_ch2, dt, factor_ch1, factor_ch2, raw_ch1_v_div y raw_ch2_v_div
                ruta = [path file];    
                info_var = ismember(who("-file", ruta), {'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'});
                if all(info_var) && length(info_var) == 5
            
	                %Si estan todas, las cargo y las almaceno en donde corresponde (variable y casilleros)
	                load(ruta, 'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2');
                    
                    try
                        % Actualizo las variables internas
                        app.factor_t = 1;
                        app.raw_data.dt = dt;
                        app.offset_t = offset_t;
                        app.raw_data.trig_pos = trig_pos;
                        app.raw_data.ch1 = ch1.raw;
                        app.raw_data.ch1_mult = ch1.ymult;
                        app.raw_data.ch1_off = ch1.yoff;
                        app.raw_data.ch1_zero = ch1.yzero;
                        app.factor_ch1 = ch1.factor;
                        app.raw_data.ch2 = ch2.raw;
                        app.raw_data.ch2_mult = ch2.ymult;
                        app.raw_data.ch2_off = ch2.yoff;
                        app.raw_data.ch2_zero = ch2.yzero;
                        app.factor_ch2 = ch2.factor;
    
    
                        %Actualizo los campos disponibles para el usuario
                        app.ef_ch1Factor.Value = ch1.factor;
                        app.ef_ch1Titulo.Value = ch1.titulo;
                        app.ef_ch1Etiqueta.Value = ch1.leyenda;
                        app.ef_ch2Factor.Value = ch2.factor;
                        app.ef_ch2Titulo.Value = ch2.titulo;
                        app.ef_ch2Etiqueta.Value = ch2.leyenda;
                        app.ef_offsetTime.Value = offset_t;
                        ylabel(app.graph_CH1, ch1.leyenda);
                        ylabel(app.graph_CH2, ch2.leyenda);
                        title(app.graph_CH1, ch1.titulo);
                        title(app.graph_CH2, ch2.titulo);
    
    
	                    app.dd_factorTime.Value = "1"; %Selector en 'Segundos'
                
	                    %Muestro el plot correspondiente
	                    plotFigures(app);
	                    updateValuesInBoxs(app);
                
	                    %Muestro el nombre del archivo cargado
                        uialert(app.AdquisicionOSC01UIFigure, "Archivo Leido: <font color='green'>" + file + "</font>", 'Apertura de Archivo .MAT', Icon='success', Interpreter='html');
    
                        %Habilito los botones
                        enableButtons(app);
                        app.lbl_NombreArchivo.Text = "Archivo: <font color='green'>" + file + "</font>";
                        app.lbl_NombreArchivo.Visible = 'on';
                    catch ME
                        app.lbl_NombreArchivo.Visible = 'off';
                        switch ME.identifier
                            case 'MATLAB:nonExistentField'
                                uialert(app.AdquisicionOSC01UIFigure, 'Los datos no corresponden a los exportados por esta aplicacion!', 'Error', Icon='error');
                            otherwise
                                uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                        end
                    end
                else
	                uialert(app.AdquisicionOSC01UIFigure, 'El archivo no contiene las variables necesarias para esta aplicacion!', 'Error al Importar', Icon='error');
                end
            end

        end

        % Button pushed function: btn_clipboardCH1
        function Export_CH1_Clipboard(app, event)
            r = uiconfirm(app.AdquisicionOSC01UIFigure, 'Elige el formato:', 'Copiar al Portapapeles' ...
                ,'Options',{'PNG', 'SVG', 'Cancelar'}, "DefaultOption",2,"CancelOption",3);
            
            
            if strcmp(r, 'PNG')
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                copygraphics(app.graph_CH1, "Resolution", 300, ContentType='image');
                d.Value = 1;
                pause(0.1);
                close(d);
            elseif strcmp(r, 'SVG')
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                copygraphics(app.graph_CH1, ContentType='vector');
                d.Value = 1;
                pause(0.1);
                close(d);
            end
            

            if ~strcmp(r, 'Cancelar')
                uialert(app.AdquisicionOSC01UIFigure, 'Imagen cargada en el Portapapeles!', 'Copiar al Portapapeles', Icon='success');
            end
        end

        % Button pushed function: btn_clipboardCH2
        function Export_CH2_Clipboard(app, event)
            r = uiconfirm(app.AdquisicionOSC01UIFigure, 'Elige el formato:', 'Copiar al Portapapeles' ...
                ,'Options',{'PNG', 'SVG', 'Cancelar'}, "DefaultOption",2,"CancelOption",3);
            
            
            if strcmp(r, 'PNG')
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                copygraphics(app.graph_CH2, "Resolution", 300, ContentType='image');
                d.Value = 1;
                pause(0.1);
                close(d);
            elseif strcmp(r, 'SVG')
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                copygraphics(app.graph_CH2, ContentType='vector');
                d.Value = 1;
                pause(0.1);
                close(d);
            end
            

            if ~strcmp(r, 'Cancelar')
                uialert(app.AdquisicionOSC01UIFigure, 'Imagen cargada en el Portapapeles!', 'Copiar al Portapapeles', Icon='success');
            end
        end

        % Button pushed function: btn_exportCH1
        function Export_CH1_Image(app, event)
            r = uiconfirm(app.AdquisicionOSC01UIFigure, 'Elige el formato:', 'Exportar' ...
                ,'Options',{'PNG', 'PDF', 'Cancelar'}, "DefaultOption",2,"CancelOption",3);
            
            
            if strcmp(r, 'PNG')
                [filename,filepath] = uiputfile('*.png');
                if ischar(filename)
                    d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                    exportgraphics(app.graph_CH1, [filepath filename], 'Resolution', 500);
                    d.Value = 0.5;
                    pause(0.1);
                    d.Value = 1;
                    close(d);
                    uialert(app.AdquisicionOSC01UIFigure, 'Imagen guardada en la ruta seleccionada!', 'Resultado', Icon='success');
                end
            elseif strcmp(r, 'PDF')
                [filename,filepath] = uiputfile('*.pdf');
                if ischar(filename)
                    d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                    exportgraphics(app.graph_CH1, [filepath filename], 'ContentType','vector');
                    d.Value = 0.5;
                    pause(0.1);
                    d.Value = 1;
                    close(d);
                    uialert(app.AdquisicionOSC01UIFigure, 'Imagen vectorial guardada en la ruta seleccionada!', 'Resultado', Icon='success');
                end
            end
        end

        % Button pushed function: btn_exportCH2
        function Export_CH2_Image(app, event)
            r = uiconfirm(app.AdquisicionOSC01UIFigure, 'Elige el formato:', 'Exportar' ...
                ,'Options',{'PNG', 'PDF', 'Cancelar'}, "DefaultOption",2,"CancelOption",3);
            
            
            if strcmp(r, 'PNG')
                [filename,filepath] = uiputfile('*.png');
                if ischar(filename)
                    d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                    exportgraphics(app.graph_CH2, [filepath filename], 'Resolution', 500);
                    d.Value = 0.5;
                    pause(0.1);
                    d.Value = 1;
                    close(d);
                    uialert(app.AdquisicionOSC01UIFigure, 'Imagen guardada en la ruta seleccionada!', 'Resultado', Icon='success');
                end
            elseif strcmp(r, 'PDF')
                [filename,filepath] = uiputfile('*.pdf');
                if ischar(filename)
                    d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Capturando Imagen...', Title='Estado', Value=0.1);
                    exportgraphics(app.graph_CH2, [filepath filename], 'ContentType','vector');
                    d.Value = 0.5;
                    pause(0.1);
                    d.Value = 1;
                    close(d);
                    uialert(app.AdquisicionOSC01UIFigure, 'Imagen vectorial guardada en la ruta seleccionada!', 'Resultado', Icon='success');
                end
            end
        end

        % Button pushed function: btn_UpdateSerialPort
        function Change_Serial_BPS(app, event)
            app.dd_serialPort.Items = serialportlist;
        end

        % Value changed function: dd_factorTime
        function Change_Factor_Time(app, event)
            app.factor_t = str2double(app.dd_factorTime.Value);

            plotFigures(app);
            
            app.graph_CH1.XLimMode = "auto";
            app.graph_CH2.XLimMode = "auto";
            
            app.ef_startTime.Value = app.computed_data.t(1);
            app.ef_endTime.Value = app.computed_data.t(end);
            
        end

        % Value changed function: ef_startTime
        function Change_Start_Time(app, event)
            init_val = app.ef_startTime.Value;
            end_val = app.graph_CH1.XLim(2);

            if(init_val > end_val)
                msgbox("El valor inicial no puede superar al valor final!");
            else
                xlim(app.graph_CH1, [init_val, end_val]);
                xlim(app.graph_CH2, [init_val, end_val]);
                ylim(app.graph_CH1, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
                ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            end
        end

        % Value changed function: ef_endTime
        function Change_End_Time(app, event)
            init_val = app.graph_CH1.XLim(1);
            end_val = app.ef_endTime.Value;
        

            if(end_val < init_val)
                msgbox("El valor final no puede superar al valor inicial!");
            else
                xlim(app.graph_CH1, [init_val, end_val]);
                xlim(app.graph_CH2, [init_val, end_val]);
                ylim(app.graph_CH1, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
                ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            end
        end

        % Button pushed function: btn_tRestore
        function Restore_Time_Axis(app, event)
            plotFigures(app);

            app.graph_CH1.XLimMode = "auto";
            app.graph_CH2.XLimMode = "auto";

            app.ef_startTime.Value = app.graph_CH1.XLim(1);
            app.ef_endTime.Value = app.graph_CH1.XLim(2);

            ylim(app.graph_CH1, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
            ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            
        end

        % Value changed function: ef_ch2Etiqueta
        function Change_Etiqueta_CH2(app, event)
            ylabel(app.graph_CH2, app.ef_ch2Etiqueta.Value);
        end

        % Value changed function: ef_ch2Titulo
        function Change_Titulo_CH2(app, event)
            title(app.graph_CH2, app.ef_ch2Titulo.Value)
        end

        % Value changed function: ef_ch1Etiqueta
        function Change_Etiqueta_CH1(app, event)
            ylabel(app.graph_CH1, app.ef_ch1Etiqueta.Value);
        end

        % Value changed function: ef_ch1Titulo
        function Change_Titulo_CH1(app, event)
            title(app.graph_CH1, app.ef_ch1Titulo.Value)
        end

        % Value changed function: ef_ch1Factor
        function Change_Factor_CH1(app, event)
            app.factor_ch1 = app.ef_ch1Factor.Value;

            plotFigures(app);
        end

        % Value changed function: ef_ch2Factor
        function Change_Factor_CH2(app, event)
            app.factor_ch2 = app.ef_ch2Factor.Value;

            plotFigures(app);
        end

        % Value changed function: ef_ch1YMin
        function Change_CH1_Y_Min(app, event)
            init_val = app.ef_ch1YMin.Value;
            end_val = app.graph_CH1.YLim(2);

            if(init_val > end_val)
                msgbox("El valor inicial no puede superar al valor final!");
            else
                ylim(app.graph_CH1, [init_val, end_val]);
            end
        end

        % Value changed function: ef_ch1YMax
        function Change_CH1_Y_Max(app, event)
            init_val = app.graph_CH1.YLim(1);
            end_val = app.ef_ch1YMax.Value;
        

            if(end_val < init_val)
                msgbox("El valor final no puede superar al valor inicial!");
            else
                ylim(app.graph_CH1, [init_val, end_val]);
            end
        end

        % Value changed function: ef_ch2YMin
        function Change_CH2_Y_Min(app, event)
            init_val = app.ef_ch2YMin.Value;
            end_val = app.graph_CH2.YLim(2);

            if(init_val > end_val)
                msgbox("El valor inicial no puede superar al valor final!");
            else
                ylim(app.graph_CH2, [init_val, end_val]);
            end
        end

        % Value changed function: ef_ch2YMax
        function Change_CH2_Y_Max(app, event)
            init_val = app.graph_CH2.YLim(1);
            end_val = app.ef_ch2YMax.Value;
        

            if(end_val < init_val)
                msgbox("El valor final no puede superar al valor inicial!");
            else
                ylim(app.graph_CH2, [init_val, end_val]);
            end
        end

        % Button pushed function: btn_ch1Restore
        function Restore_CH1_YAxis(app, event)
            app.graph_CH1.YLimMode = "auto";
            %ylim(app.graph_CH1, [min(app.computed_data.ch1), max(app.computed_data.ch1)])
            app.ef_ch1YMin.Value = app.graph_CH1.YLim(1);
            app.ef_ch1YMax.Value = app.graph_CH1.YLim(2);
            
            xlim(app.graph_CH1, [app.ef_startTime.Value, app.ef_endTime.Value]);
            xlim(app.graph_CH2, [app.ef_startTime.Value, app.ef_endTime.Value]);
        end

        % Button pushed function: btn_ch2Restore
        function Restore_CH2_YAxis(app, event)
            app.graph_CH2.YLimMode = "auto";
            %ylim(app.graph_CH2, [min(app.computed_data.ch2), max(app.computed_data.ch2)])
            app.ef_ch2YMin.Value = app.graph_CH2.YLim(1);
            app.ef_ch2YMax.Value = app.graph_CH2.YLim(2);
        end

        % Value changed function: sw_ch1Enable
        function Change_Switch_CH1(app, event)

            if(strcmp(app.sw_ch1Enable.Value, "On"))
                app.lamp_ch1Enable.Color = [0.39;0.83;0.07];
            else
                app.lamp_ch1Enable.Color = [0.50;0.50;0.50];
                if(strcmp(app.sw_ch2Enable.Value, 'Off'))
                    app.lamp_ch2Enable.Color = [0.39;0.83;0.07];
                    app.sw_ch2Enable.Value = 'On';
                end
            end
        end

        % Value changed function: sw_ch2Enable
        function Change_Switch_CH2(app, event)
            if(strcmp(app.sw_ch2Enable.Value, "On"))
                app.lamp_ch2Enable.Color = [0.39;0.83;0.07];
            else
                app.lamp_ch2Enable.Color = [0.50;0.50;0.50];
                if(strcmp(app.sw_ch1Enable.Value, 'Off'))
                    app.lamp_ch1Enable.Color = [0.39;0.83;0.07];
                    app.sw_ch1Enable.Value = 'On';
                end
            end
        end

        % Value changed function: ef_offsetTime
        function Change_Offset_Time(app, event)
            app.offset_t = app.ef_offsetTime.Value;
            plotFigures(app);
        end

        % Image clicked function: btn_imgHelp
        function Click_Help_Window(app, event)
            Ventana_Help;
%             uialert(app.AdquisicionOSC01UIFigure, ...
%                 "<font size=2>" + ...
%                 "<h3>Adquisidor para Tektronix TDS360</h3>" + ...
%                 "<h4>Conexion Serial</h4>" + ...
%                 "<p>Esta aplicacion permite conectarse por comunicacion serial unicamente al modelo de Osciloscopio TDS360. " + ...
%                 "Una vez realizada la conexion se procedera a presionar el boton <b>Leer Valores</b>.</p>" + ...
%                 "<p>Esto descarga la totalidad de las muestras disponibles para cada canal.</p> <br>" + ...
%                 "<h4>Descarga de Valores</h4>" + ...
%                 "<p>Una vez que se leyeron todos los valores, no necesariamente ambos canales al mismo tiempo, es " + ...
%                 "posible descargar los datos en formato legible por MatLab (.MAT). La estructura de los valores tendra la forma:</p>" + ...
%                 "<ul><li><b>dt</b>: Es el valor del tiempo entre muestras.</li>" + ...
%                 "<li><b>offset_t</b>: Es el desplazamiento que se realiza en el vector tiempo.</li>" + ...
%                 "<li><b>ch1</b>: Contiene los datos del canal 1.</li>" + ...
%                 "<ul><li><b>ch1.raw</b>: Contiene los valores RAW que se leyeron del Osciloscopio.</li>" + ...
%                 "<li><b>ch1.ymult</b>: Es el factor por el cual se debera multiplicar la muestra.</li>" + ...
%                 "<li><b>ch1.yoff</b>: Es el valor que se le debera restar al valor RAW leido (antes de usar el multiplicador).</li>" + ...
%                 "<li><b>ch1.yzero</b>: Es el valor que se debera sumar al valor de tension final.</li>" + ...
%                 "<li><b>ch1.titulo</b>: Corresponde al Titulo que se le deja a la grafica.</li>" + ...
%                 "<li><b>ch1.leyenda</b>: Corresponde a la leyenda que se le deja al eje vertical.</li>" + ...
%                 "</ul>" + ...
%                 "<li><b>ch2</b>: Misma situacion que con ch1</li>" + ...
%                 "</ul>" + ...
%                 "<p>Estos campos estaran dentro de el archivo .MAT.</p><br>" + ...
%                 "<h4>Carga de Archivo .MAT</h4>" + ...
%                 "<p>El proceso inverso consta de elegir un archivo .MAT, de esta forma primero se comprueba que todas las " + ...
%                 "variables que previamente se nombraron, existan en el archivo</p>" + ...
%                 "<p>Finalmente, se cargan en los lugares correspondientes y luego se realiza la grafica</p>" + ...
%                 "</font>" , 'Titulo', Interpreter='html', Icon='none');
        end

        % Close request function: AdquisicionOSC01UIFigure
        function Close_App(app, event)
            delete(Ventana_Help);
            delete(app)
        end

        % Image clicked function: img_Logo
        function img_LogoImageClicked(app, event)
            web('https://leme.ing.unlp.edu.ar/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdquisicionOSC01UIFigure = uifigure('Visible', 'off');
            app.AdquisicionOSC01UIFigure.Name = 'Adquisicion OSC01';
            app.AdquisicionOSC01UIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'icon.png');
            app.AdquisicionOSC01UIFigure.CloseRequestFcn = createCallbackFcn(app, @Close_App, true);
            app.graph_CH1 = uiaxes(app.AdquisicionOSC01UIFigure);
            title(app.graph_CH1, 'Canal 1')
            xlabel(app.graph_CH1, 'Tiempo [s]')
            ylabel(app.graph_CH1, 'Canal 1 [v]')
            zlabel(app.graph_CH1, 'Z')
            app.graph_CH2 = uiaxes(app.AdquisicionOSC01UIFigure);
            title(app.graph_CH2, 'Canal 2')
            xlabel(app.graph_CH2, 'Tiempo [s]')
            ylabel(app.graph_CH2, 'Canal 2 [v]')
            zlabel(app.graph_CH2, 'Z')
            app.img_Logo = uiimage(app.AdquisicionOSC01UIFigure);
            app.img_Logo.ImageClickedFcn = createCallbackFcn(app, @img_LogoImageClicked, true);
            app.img_Logo.ImageSource = fullfile(pathToMLAPP, 'assets', 'Logo_LEME.png');
            app.tab_TipoConexion = uitabgroup(app.AdquisicionOSC01UIFigure);
            app.ConexionSerialTab = uitab(app.tab_TipoConexion);
            app.ConexionSerialTab.Title = 'Conexion Serial';
            app.lbl_serialPort = uilabel(app.ConexionSerialTab);
            app.lbl_serialPort.Text = 'Puerto COM:';
            app.dd_serialPort = uidropdown(app.ConexionSerialTab);
            app.btn_UpdateSerialPort = uibutton(app.ConexionSerialTab, 'push');
            app.btn_UpdateSerialPort.ButtonPushedFcn = createCallbackFcn(app, @Change_Serial_BPS, true);
            app.btn_UpdateSerialPort.Text = 'Actualizar';
            app.lbl_serialBPS = uilabel(app.ConexionSerialTab);
            app.lbl_serialBPS.Text = 'Velocidad:';
            app.ef_serialBPS = uieditfield(app.ConexionSerialTab, 'numeric');
            app.ef_serialBPS.ValueDisplayFormat = '%.0f';
            app.ef_serialBPS.Value = 9600;
            app.btn_serialConnect = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialConnect.ButtonPushedFcn = createCallbackFcn(app, @Serial_Connect, true);
            app.btn_serialConnect.Text = 'Conectar';
            app.sw_ch1Enable = uiswitch(app.ConexionSerialTab, 'slider');
            app.sw_ch1Enable.ValueChangedFcn = createCallbackFcn(app, @Change_Switch_CH1, true);
            app.sw_ch1Enable.Value = 'On';
            app.lamp_ch1Enable = uilamp(app.ConexionSerialTab);
            app.btn_serialRead = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialRead.ButtonPushedFcn = createCallbackFcn(app, @Serial_Read, true);
            app.btn_serialRead.Enable = 'off';
            app.btn_serialRead.Text = 'Leer Valores';
            app.sw_ch2Enable = uiswitch(app.ConexionSerialTab, 'slider');
            app.sw_ch2Enable.ValueChangedFcn = createCallbackFcn(app, @Change_Switch_CH2, true);
            app.sw_ch2Enable.Value = 'On';
            app.lamp_ch2Enable = uilamp(app.ConexionSerialTab);
            app.lbl_ch2Enable = uilabel(app.ConexionSerialTab);
            app.lbl_ch2Enable.Text = 'Canal 2';
            app.lbl_ch1Enable = uilabel(app.ConexionSerialTab);
            app.lbl_ch1Enable.Text = 'Canal 1';
            app.tab_AjusteGraficas = uitabgroup(app.AdquisicionOSC01UIFigure);
            app.tab_AjusteTiempo = uitab(app.tab_AjusteGraficas);
            app.tab_AjusteTiempo.Tooltip = {''};
            app.tab_AjusteTiempo.Title = 'Ajuste de Tiempo';
            app.lbl_factorTime = uilabel(app.tab_AjusteTiempo);
            app.lbl_factorTime.Text = 'Escala de Tiempo';
            app.dd_factorTime = uidropdown(app.tab_AjusteTiempo);
            app.dd_factorTime.Items = {'(ps) Picosegundos', '(ns) Nanosegundos', '(µs) Microsegundos', '(ms) Milisegundos', '(s) Segundos'};
            app.dd_factorTime.ItemsData = {'1e-12', '1e-9', '1e-6', '1e-3', '1'};
            app.dd_factorTime.ValueChangedFcn = createCallbackFcn(app, @Change_Factor_Time, true);
            app.dd_factorTime.Value = '1';
            app.OffsetLabel = uilabel(app.tab_AjusteTiempo);
            app.OffsetLabel.Text = 'Offset';
            app.ef_offsetTime = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_offsetTime.ValueChangedFcn = createCallbackFcn(app, @Change_Offset_Time, true);
            app.lbl_startTime = uilabel(app.tab_AjusteTiempo);
            app.lbl_startTime.Text = 'Tiempo Inicial:';
            app.ef_startTime = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_startTime.ValueChangedFcn = createCallbackFcn(app, @Change_Start_Time, true);
            app.lbl_endTime = uilabel(app.tab_AjusteTiempo);
            app.lbl_endTime.Text = 'Tiempo Final:';
            app.ef_endTime = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_endTime.ValueChangedFcn = createCallbackFcn(app, @Change_End_Time, true);
            app.lbl_valPretrigger = uilabel(app.tab_AjusteTiempo);
            app.lbl_valPretrigger.Text = 'Pretigrer';
            app.ef_valPretrigger = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_valPretrigger.Editable = 'off';
            app.btn_tRestore = uibutton(app.tab_AjusteTiempo, 'push');
            app.btn_tRestore.ButtonPushedFcn = createCallbackFcn(app, @Restore_Time_Axis, true);
            app.btn_tRestore.Icon = fullfile(pathToMLAPP, 'assets', 'restore.png');
            app.btn_tRestore.Text = '';
            app.tab_AjusteCH1 = uitab(app.tab_AjusteGraficas);
            app.tab_AjusteCH1.Title = 'Ajuste CH1';
            app.lbl_ch1Titulo = uilabel(app.tab_AjusteCH1);
            app.lbl_ch1Titulo.Text = 'Titulo';
            app.ef_ch1Titulo = uieditfield(app.tab_AjusteCH1, 'text');
            app.ef_ch1Titulo.ValueChangedFcn = createCallbackFcn(app, @Change_Titulo_CH1, true);
            app.lbl_ch1Etiqueta = uilabel(app.tab_AjusteCH1);
            app.lbl_ch1Etiqueta.Text = 'Etiqueta';
            app.ef_ch1Etiqueta = uieditfield(app.tab_AjusteCH1, 'text');
            app.ef_ch1Etiqueta.ValueChangedFcn = createCallbackFcn(app, @Change_Etiqueta_CH1, true);
            app.lbl_ch1Factor = uilabel(app.tab_AjusteCH1);
            app.lbl_ch1Factor.Text = 'Multiplicador';
            app.ef_ch1Factor = uieditfield(app.tab_AjusteCH1, 'numeric');
            app.ef_ch1Factor.ValueDisplayFormat = '%2.2g';
            app.ef_ch1Factor.ValueChangedFcn = createCallbackFcn(app, @Change_Factor_CH1, true);
            app.ef_ch1Factor.Value = 1;
            app.lbl_ch1YMin = uilabel(app.tab_AjusteCH1);
            app.lbl_ch1YMin.Text = 'Valor Minimo';
            app.ef_ch1YMin = uieditfield(app.tab_AjusteCH1, 'numeric');
            app.ef_ch1YMin.ValueDisplayFormat = '%2.2g';
            app.ef_ch1YMin.ValueChangedFcn = createCallbackFcn(app, @Change_CH1_Y_Min, true);
            app.lbl_ch1YMax = uilabel(app.tab_AjusteCH1);
            app.lbl_ch1YMax.Text = 'Valor Maximo';
            app.ef_ch1YMax = uieditfield(app.tab_AjusteCH1, 'numeric');
            app.ef_ch1YMax.ValueDisplayFormat = '%2.2g';
            app.ef_ch1YMax.ValueChangedFcn = createCallbackFcn(app, @Change_CH1_Y_Max, true);
            app.btn_ch1Restore = uibutton(app.tab_AjusteCH1, 'push');
            app.btn_ch1Restore.ButtonPushedFcn = createCallbackFcn(app, @Restore_CH1_YAxis, true);
            app.btn_ch1Restore.Icon = fullfile(pathToMLAPP, 'assets', 'restore.png');
            app.btn_ch1Restore.Text = '';
            app.tab_AjusteCH2 = uitab(app.tab_AjusteGraficas);
            app.tab_AjusteCH2.Title = 'Ajuste CH2';
            app.lbl_ch2Titulo = uilabel(app.tab_AjusteCH2);
            app.lbl_ch2Titulo.Text = 'Titulo';
            app.ef_ch2Titulo = uieditfield(app.tab_AjusteCH2, 'text');
            app.ef_ch2Titulo.ValueChangedFcn = createCallbackFcn(app, @Change_Titulo_CH2, true);
            app.lbl_ch2Etiqueta = uilabel(app.tab_AjusteCH2);
            app.lbl_ch2Etiqueta.Text = 'Etiqueta';
            app.ef_ch2Etiqueta = uieditfield(app.tab_AjusteCH2, 'text');
            app.ef_ch2Etiqueta.ValueChangedFcn = createCallbackFcn(app, @Change_Etiqueta_CH2, true);
            app.lbl_ch2Factor = uilabel(app.tab_AjusteCH2);
            app.lbl_ch2Factor.Text = 'Multiplicador';
            app.ef_ch2Factor = uieditfield(app.tab_AjusteCH2, 'numeric');
            app.ef_ch2Factor.ValueDisplayFormat = '%2.2g';
            app.ef_ch2Factor.ValueChangedFcn = createCallbackFcn(app, @Change_Factor_CH2, true);
            app.ef_ch2Factor.Value = 1;
            app.lbl_ch2YMin = uilabel(app.tab_AjusteCH2);
            app.lbl_ch2YMin.Text = 'Valor Minimo';
            app.ef_ch2YMin = uieditfield(app.tab_AjusteCH2, 'numeric');
            app.ef_ch2YMin.ValueDisplayFormat = '%2.2g';
            app.ef_ch2YMin.ValueChangedFcn = createCallbackFcn(app, @Change_CH2_Y_Min, true);
            app.lbl_ch2Ymax = uilabel(app.tab_AjusteCH2);
            app.lbl_ch2Ymax.Text = 'Valor Maximo';
            app.ef_ch2YMax = uieditfield(app.tab_AjusteCH2, 'numeric');
            app.ef_ch2YMax.ValueDisplayFormat = '%2.2g';
            app.ef_ch2YMax.ValueChangedFcn = createCallbackFcn(app, @Change_CH2_Y_Max, true);
            app.btn_ch2Restore = uibutton(app.tab_AjusteCH2, 'push');
            app.btn_ch2Restore.ButtonPushedFcn = createCallbackFcn(app, @Restore_CH2_YAxis, true);
            app.btn_ch2Restore.Icon = fullfile(pathToMLAPP, 'assets', 'restore.png');
            app.btn_ch2Restore.Text = '';
            app.btn_imgHelp = uiimage(app.AdquisicionOSC01UIFigure);
            app.btn_imgHelp.ImageClickedFcn = createCallbackFcn(app, @Click_Help_Window, true);
            app.btn_imgHelp.ImageSource = fullfile(pathToMLAPP, 'assets', 'help-icon.png');
            app.btn_abrirArchivoDAT = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_abrirArchivoDAT.ButtonPushedFcn = createCallbackFcn(app, @Import_MAT, true);
            app.btn_abrirArchivoDAT.Text = 'Importar Archivo .MAT';
            app.btn_ExportMAT = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_ExportMAT.ButtonPushedFcn = createCallbackFcn(app, @Export_MAT, true);
            app.btn_ExportMAT.Tooltip = {'El formato .MAT guarda los metadatos correspondientes a la configuracion de las gráficas.'};
            app.btn_ExportMAT.Text = 'Exportar en Archivo .MAT';
            app.btn_ExportCSV = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_ExportCSV.ButtonPushedFcn = createCallbackFcn(app, @Export_CSV, true);
            app.btn_ExportCSV.Text = 'Exportar en Archivo .CSV';
            app.btn_exportCH1 = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_exportCH1.ButtonPushedFcn = createCallbackFcn(app, @Export_CH1_Image, true);
            app.btn_exportCH1.Icon = fullfile(pathToMLAPP, 'assets', 'save-icon.png');
            app.btn_exportCH1.Tooltip = {'Permite guardar el grafico en una imagen ''PNG'' o en un ''PDF'''};
            app.btn_exportCH1.Text = '';
            app.btn_exportCH2 = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_exportCH2.ButtonPushedFcn = createCallbackFcn(app, @Export_CH2_Image, true);
            app.btn_exportCH2.Icon = fullfile(pathToMLAPP, 'assets', 'save-icon.png');
            app.btn_exportCH2.Tooltip = {'Permite guardar el grafico en una imagen ''PNG'' o en un ''PDF'''};
            app.btn_exportCH2.Text = '';
            app.btn_clipboardCH1 = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_clipboardCH1.ButtonPushedFcn = createCallbackFcn(app, @Export_CH1_Clipboard, true);
            app.btn_clipboardCH1.Icon = fullfile(pathToMLAPP, 'assets', 'clipboard.png');
            app.btn_clipboardCH1.Tooltip = {'Permite copiar en el portapapeles la imagen en formato ''PNG'' o en un ''PDF'''};
            app.btn_clipboardCH1.Text = '';
            app.btn_clipboardCH2 = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_clipboardCH2.ButtonPushedFcn = createCallbackFcn(app, @Export_CH2_Clipboard, true);
            app.btn_clipboardCH2.Icon = fullfile(pathToMLAPP, 'assets', 'clipboard.png');
            app.btn_clipboardCH2.Tooltip = {'Permite copiar en el portapapeles la imagen en formato ''PNG'' o en un ''PDF'''};
            app.btn_clipboardCH2.Text = '';
            app.lbl_NombreArchivo = uilabel(app.AdquisicionOSC01UIFigure);
            app.lbl_NombreArchivo.Visible = 'off';
            % Show the figure after all components are created
            app.AdquisicionOSC01UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdquisicionOSC01UIFigure)

                % Execute the startup function
                runStartupFcn(app, @onLoad)
            else

                % Focus the running singleton app
                figure(runningApp.AdquisicionOSC01UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdquisicionOSC01UIFigure)
        end
    end
end